classdef GUI_Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        BehaviorsLabel              matlab.ui.control.Label
        ObstaclesLabel              matlab.ui.control.Label
        RightCheckBox               matlab.ui.control.CheckBox
        SelectAllCheckBox_2         matlab.ui.control.CheckBox
        StraightCheckBox            matlab.ui.control.CheckBox
        LeftCheckBox                matlab.ui.control.CheckBox
        SelectAllCheckBox_1         matlab.ui.control.CheckBox
        CarCheckBox                 matlab.ui.control.CheckBox
        PedestrianCheckBox          matlab.ui.control.CheckBox
        ClearCheckBox               matlab.ui.control.CheckBox
        ScenesListBox               matlab.ui.control.ListBox
        ScenesListBoxLabel          matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        ScenesSelectedListBox       matlab.ui.control.ListBox
        ScenesSelectedListBoxLabel  matlab.ui.control.Label
        ObstaclesListBox            matlab.ui.control.ListBox
        ObstaclesListBox_2Label     matlab.ui.control.Label
        BehaviorsListBox            matlab.ui.control.ListBox
        BehaviorsListBoxLabel       matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CarCheckBox, ClearCheckBox, 
        % ...and 1 other component
        function UpdateObstacles(app, event)
        
            selectedItemsObstacles = {};
            is1Checked = app.ClearCheckBox.Value;
            is2Checked = app.PedestrianCheckBox.Value;
            is3Checked = app.CarCheckBox.Value;
            
            % Check if any boxes are checked
            if is1Checked % Checkbox is checked if Value is true
                selectedItemsObstacles{end+1} = 'Clear';
            end

            if is2Checked
                selectedItemsObstacles{end+1} = 'Pedestrian';
            end

            if is3Checked
                selectedItemsObstacles{end+1} = 'Car';
            end
           
            % Update the List Box with the new items
            app.ObstaclesListBox.Items = selectedItemsObstacles;

            % If ALL individual boxes are checked, set "Select All" to checked (true).
            % Otherwise, set "Select All" to unchecked (false).
            if is1Checked && is2Checked && is3Checked
                app.SelectAllCheckBox_1.Value = true;
            else
                app.SelectAllCheckBox_1.Value = false;
            end

        end

        % Value changed function: SelectAllCheckBox_1
        function SelectAllCheckBoxValueChanged_1(app, event)
            % Get the current value (checked/unchecked) of the "Select All" box
            isSelectAllChecked = app.SelectAllCheckBox_1.Value;

            % Set the value of the other three checkboxes
            app.CarCheckBox.Value = isSelectAllChecked;
            app.PedestrianCheckBox.Value = isSelectAllChecked;
            app.ClearCheckBox.Value = isSelectAllChecked;

            % IMPORTANT: Immediately call the list update function
            app.UpdateObstacles();
            
        end

        % Value changed function: LeftCheckBox, RightCheckBox, 
        % ...and 1 other component
        function UpdateBehaviors(app, event)
            
            selectedItemsBehaviors = {};
            
            is1Checked = app.LeftCheckBox.Value;
            is2Checked = app.RightCheckBox.Value;
            is3Checked = app.StraightCheckBox.Value;
            
            % Check if any boxes are checked
            if is1Checked % Checkbox is checked if Value is true
                selectedItemsBehaviors{end+1} = 'Left';
            end

            if is2Checked
                selectedItemsBehaviors{end+1} = 'Right';
            end

            if is3Checked
                selectedItemsBehaviors{end+1} = 'Straight';
            end
           
            % Update the List Box with the new items
            app.BehaviorsListBox.Items = selectedItemsBehaviors;

            % If ALL individual boxes are checked, set "Select All" to checked (true).
            % Otherwise, set "Select All" to unchecked (false).
            if is1Checked && is2Checked && is3Checked
                app.SelectAllCheckBox_2.Value = true;
            else
                app.SelectAllCheckBox_2.Value = false;
            end
            
        end

        % Value changed function: SelectAllCheckBox_2
        function SelectAllCheckBoxValueChanged_2(app, event)
             % Get the current value (checked/unchecked) of the "Select All" box
            isSelectAllChecked = app.SelectAllCheckBox_2.Value;

            % Set the value of the other three checkboxes
            app.RightCheckBox.Value = isSelectAllChecked;
            app.LeftCheckBox.Value = isSelectAllChecked;
            app.StraightCheckBox.Value = isSelectAllChecked;

            % IMPORTANT: Immediately call the list update function
            app.UpdateBehaviors();
            
        end

        % Value changed function: ScenesListBox
        function ScenesListBoxValueChanged(app, event)
            % Get the string value of the selected item in the Scenes List Box
            selectedScene = app.ScenesListBox.Value;
        
            % Initialize an empty cell array for the list box items
            resultingListItems = {};

            % --- Logic to determine list content based on selection ---

            if strcmp(selectedScene, 'Four Way Stop')
                % If "Four Way Stop" is selected
                resultingListItems = {'Four Way Stop'};

            elseif strcmp(selectedScene, 'Four Way Light')
                % If "Four Way Light" is selected
                resultingListItems = {'Four Way Light'};
            
            else
                % If nothing is selected (or an unhandled case), clear the list
                resultingListItems = {};
            end

            % --- Update the Target List Box ---
            app.ScenesSelectedListBox.Items = resultingListItems;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ScenesListBoxLabel = uilabel(app.LeftPanel);
            app.ScenesListBoxLabel.Text = 'Scenes';
            app.ScenesListBox = uilistbox(app.LeftPanel);
            app.ScenesListBox.Items = {'Four Way Stop', 'Four Way Light'};
            app.ScenesListBox.ValueChangedFcn = createCallbackFcn(app, @ScenesListBoxValueChanged, true);
            app.ScenesListBox.Value = 'Four Way Stop';
            app.ClearCheckBox = uicheckbox(app.LeftPanel);
            app.ClearCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateObstacles, true);
            app.ClearCheckBox.Text = 'Clear';
            app.ClearCheckBox.Value = true;
            app.PedestrianCheckBox = uicheckbox(app.LeftPanel);
            app.PedestrianCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateObstacles, true);
            app.PedestrianCheckBox.Text = 'Pedestrian';
            app.PedestrianCheckBox.Value = true;
            app.CarCheckBox = uicheckbox(app.LeftPanel);
            app.CarCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateObstacles, true);
            app.CarCheckBox.Text = 'Car';
            app.CarCheckBox.Value = true;
            app.SelectAllCheckBox_1 = uicheckbox(app.LeftPanel);
            app.SelectAllCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged_1, true);
            app.SelectAllCheckBox_1.Text = 'Select All';
            app.SelectAllCheckBox_1.Value = true;
            app.LeftCheckBox = uicheckbox(app.LeftPanel);
            app.LeftCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateBehaviors, true);
            app.LeftCheckBox.Text = 'Left';
            app.LeftCheckBox.Value = true;
            app.StraightCheckBox = uicheckbox(app.LeftPanel);
            app.StraightCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateBehaviors, true);
            app.StraightCheckBox.Text = 'Straight';
            app.StraightCheckBox.Value = true;
            app.SelectAllCheckBox_2 = uicheckbox(app.LeftPanel);
            app.SelectAllCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @SelectAllCheckBoxValueChanged_2, true);
            app.SelectAllCheckBox_2.Text = 'Select All';
            app.SelectAllCheckBox_2.Value = true;
            app.RightCheckBox = uicheckbox(app.LeftPanel);
            app.RightCheckBox.ValueChangedFcn = createCallbackFcn(app, @UpdateBehaviors, true);
            app.RightCheckBox.Text = 'Right';
            app.RightCheckBox.Value = true;
            app.ObstaclesLabel = uilabel(app.LeftPanel);
            app.ObstaclesLabel.Text = 'Obstacles';
            app.BehaviorsLabel = uilabel(app.LeftPanel);
            app.BehaviorsLabel.Text = 'Behaviors';
            app.RightPanel = uipanel(app.GridLayout);
            app.BehaviorsListBoxLabel = uilabel(app.RightPanel);
            app.BehaviorsListBoxLabel.Text = 'Behaviors';
            app.BehaviorsListBox = uilistbox(app.RightPanel);
            app.BehaviorsListBox.Items = {'Left', 'Right', 'Straight'};
            app.BehaviorsListBox.Value = 'Straight';
            app.ObstaclesListBox_2Label = uilabel(app.RightPanel);
            app.ObstaclesListBox_2Label.Text = 'Obstacles';
            app.ObstaclesListBox = uilistbox(app.RightPanel);
            app.ObstaclesListBox.Items = {'Car', 'Pedestrian', 'Clear'};
            app.ObstaclesListBox.Value = 'Car';
            app.ScenesSelectedListBoxLabel = uilabel(app.RightPanel);
            app.ScenesSelectedListBoxLabel.Text = 'ScenesSelected';
            app.ScenesSelectedListBox = uilistbox(app.RightPanel);
            app.ScenesSelectedListBox.Items = {'Four Way Stop'};
            app.ScenesSelectedListBox.Value = 'Four Way Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end