classdef EndUserGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        VisualsLabel   matlab.ui.control.Label
        PassFailLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        SelectedScenarios cell = {}

        Fails

        RunLabels string = string.empty

        PlotsFolder char = 'Plots'
    end
    
    properties (Access = public)
        CollisionDetectionLamp        matlab.ui.control.Lamp
        CollisionDetectionLabel       matlab.ui.control.Label
        JerkLamp                      matlab.ui.control.Lamp
        JerkLampLabel                 matlab.ui.control.Label
        CurvatureLamp                 matlab.ui.control.Lamp
        CurvatureLampLabel            matlab.ui.control.Label
        CurvatureRateLamp             matlab.ui.control.Lamp
        CurvatureRateLampLabel        matlab.ui.control.Label
        LateralAccelerationLamp       matlab.ui.control.Lamp
        LateralAccelerationLampLabel  matlab.ui.control.Label
        TabGroup                      matlab.ui.container.TabGroup
        MapTab                        matlab.ui.container.Tab
        SpeedTab                      matlab.ui.container.Tab
        LanePositionTab               matlab.ui.container.Tab
        JerkTab                       matlab.ui.container.Tab
        LatAccelTab                   matlab.ui.container.Tab
        LonglAccelTab                 matlab.ui.container.Tab
        CurvatureTab                  matlab.ui.container.Tab
        CurvatureRateTab              matlab.ui.container.Tab
        CollisionDetectionTab         matlab.ui.container.Tab
        LongitudinalAccelerationLamp  matlab.ui.control.Lamp
        LongitudinalAccelerationLampLabel  matlab.ui.control.Label
        LaneKeepLamp                  matlab.ui.control.Lamp
        LaneKeepLampLabel             matlab.ui.control.Label
        % Added UI
        ViewDropDownLabel             matlab.ui.control.Label
        ViewDropDown                  matlab.ui.control.DropDown
        ScenarioTextArea              matlab.ui.control.TextArea

        ComfortLampLabel              matlab.ui.control.Label
        ComfortLamp                   matlab.ui.control.Lamp

       % PlotListLabel                 matlab.ui.control.Label
        PlotList                      matlab.ui.control.ListBox

        % One image per tab (simple and reliable)
        MapImage                      matlab.ui.control.Image
        SpeedImage                    matlab.ui.control.Image
        LanePosImage                  matlab.ui.control.Image
        JerkImage                     matlab.ui.control.Image
        LatAccelImage                 matlab.ui.control.Image
        LongAccelImage                matlab.ui.control.Image
        CurvatureImage                matlab.ui.control.Image
        CurvatureRateImage            matlab.ui.control.Image
        CollisionDetectionImage       matlab.ui.control.Image
    end
   
    methods (Access = private)
                % ===== Your existing functions =====
        function updateView(app)
            v = string(app.ViewDropDown.Value);
            
            % ----- Determine selected run index -----
            if v == "All"
        
                idx = 1:numel(app.Fails);
                scenarioBase = app.keyForRun(1);   % show first scenario only
                simIndex = 1;
                app.ScenarioTextArea.Value = "Viewing: ALL simulations";
        
            else
        
                k = app.parseSimIndex(v);
        
                if isempty(k) || k < 1 || k > numel(app.Fails)
                    idx = 1:numel(app.Fails);
                    scenarioBase = app.keyForRun(1);
                    simIndex = 1;
                else
                    idx = k;
                    scenarioBase = app.keyForRun(k);
                    simIndex = k;
                    
                end
        
                app.ScenarioTextArea.Value = "Viewing: " + v;
        
            end
        
            % ----- Update Lamps -----
            app.updateLampsForRuns(idx);
        
            % ----- Load Plots -----
            app.loadScenarioPlots(scenarioBase, simIndex);
        
        end
        function updateLampsForRuns(app, runIdx)

            F = app.Fails(runIdx);
        
            laneFail = any(arrayfun(@(s) app.safeFailFlag(s,'LanePosition'), F));
            longFail = any(arrayfun(@(s) app.safeFailFlag(s,'LongitudinalAcceleration'), F));
            latFail  = any(arrayfun(@(s) app.safeFailFlag(s,'LateralAcceleration'), F));
            curvFail = any(arrayfun(@(s) app.safeFailFlag(s,'Curvature'), F));
            curvRateFail = any(arrayfun(@(s) app.safeFailFlag(s,'CurvatureRate'), F));
            jerkFail = any(arrayfun(@(s) app.safeFailFlag(s,'Jerk'), F));
            collFail = any(arrayfun(@(s) app.safeFailFlag(s,'CollisionDetection'), F));
        
            app.LaneKeepLamp.Color                 = app.redGreen(laneFail);
            app.LongitudinalAccelerationLamp.Color = app.redGreen(longFail);
            app.LateralAccelerationLamp.Color      = app.redGreen(latFail);
            app.CurvatureLamp.Color                = app.redGreen(curvFail);
            app.CurvatureRateLamp.Color            = app.redGreen(curvRateFail);
            app.JerkLamp.Color                     = app.redGreen(jerkFail);
            app.CollisionDetectionLamp.Color       = app.redGreen(collFail);
        
            % Comfort = LongAccel + LatAccel + Jerk
            longFails = arrayfun(@(s) app.safeFailFlag(s,'LongAccel'), F);
            latFails  = arrayfun(@(s) app.safeFailFlag(s,'LatAccel'), F);
            jerkFails = arrayfun(@(s) app.safeFailFlag(s,'Jerk'), F);
            curvFails = any(arrayfun(@(s) app.safeFailFlag(s,'Curvature'), F));
            curvRateFails = any(arrayfun(@(s) app.safeFailFlag(s,'CurvatureRate'), F));

            comfortMatrix = [longFails(:), latFails(:), jerkFails(:), curvFails(:), curvRateFails(:)];
            app.ComfortLamp.Color = app.comfortColor(comfortMatrix);
        
        end
        function loadScenarioPlots(app, scenarioBase, simIndex)

            if strlength(scenarioBase) == 0
                return
            end
        
            plotsRoot = app.PlotsFolder;
            app.setImageIfExists(fullfile(plotsRoot,'Speed and Map',scenarioBase + "_" + simIndex + "_Map.png"), app.MapImage);
            app.setImageIfExists(fullfile(plotsRoot,'Speed and Map',scenarioBase + "_" + simIndex + "_Speeds.png"), app.SpeedImage);
        
            app.setFeaturePlot(scenarioBase,"LanePosition",app.LanePosImage,plotsRoot, simIndex);
            app.setFeaturePlot(scenarioBase,"Smoothness_Jerk",app.JerkImage,plotsRoot, simIndex);
            app.setFeaturePlot(scenarioBase,"Smoothness_LatAccel",app.LatAccelImage,plotsRoot, simIndex);
            app.setFeaturePlot(scenarioBase,"Smoothness_LongAccel",app.LongAccelImage,plotsRoot, simIndex);
            app.setFeaturePlot(scenarioBase,"Smoothness_Curvature",app.CurvatureImage,plotsRoot, simIndex);
            app.setFeaturePlot(scenarioBase,"Smoothness_CurvatureRate",app.CurvatureRateImage,plotsRoot, simIndex);
            app.setFeaturePlot(scenarioBase,"CollisionDistance",app.CollisionDetectionImage,plotsRoot, simIndex);

        end


        function setFeaturePlot(app, scenarioBase, featureName, targetImage, plotsRoot, simIndex)

            candidates = [
                fullfile(plotsRoot,'Fail', scenarioBase + "_" + simIndex + "_" + featureName + "_Fail.png")
                fullfile(plotsRoot,'Fail', scenarioBase + "_" + simIndex + "_" + featureName + "_fail.png")
                fullfile(plotsRoot,'Fail', scenarioBase + "_" + simIndex + "_" + featureName + ".png")
                fullfile(plotsRoot,'Pass', scenarioBase + "_" + simIndex + "_" + featureName + "_Pass.png")
                fullfile(plotsRoot,'Pass', scenarioBase + "_" + simIndex + "_" + featureName + "_pass.png")
                fullfile(plotsRoot,'Pass', scenarioBase + "_" + simIndex + "_" + featureName + ".png")
            ];
        
            for i = 1:numel(candidates)
                if isfile(candidates(i))
                    targetImage.ImageSource = candidates(i);
                    return
                end
            end
        
            targetImage.ImageSource = [];
        end

        function setImageIfExists(app, fullPath, targetImage)

            if isfile(fullPath)
                targetImage.ImageSource = fullPath;
            else
                targetImage.ImageSource = [];
            end

        end
        function f = safeFailFlag(app, s, fieldName) %#ok<INUSD>
            if ~isstruct(s) || ~isfield(s, fieldName)
                f = 0; return;
            end
            v = s.(fieldName);
            if isempty(v)
                f = 0; return;
            end
            if isnumeric(v) || islogical(v)
                f = double(any(v(:) ~= 0));
            else
                f = 0;
            end
        end

        function fails = loadFails(app)

            fails = [];
        
            % -------- 1. Try base workspace --------
            try
                if evalin('base',"exist('fails','var') == 1")
                    tmp = evalin('base','fails');
                    fails = tmp;
                end
            catch
            end
        
           % -------- 2. If still empty, try fails.mat in current folder --------
            if isempty(fails) && isfile('fails.mat')
                S = load('fails.mat');
        
                if isfield(S,'fails')
                    fails = S.fails;
                else
                    fn = fieldnames(S);
                    if ~isempty(fn)
                        fails = S.(fn{1});
                    end
                end
            end
        
            % -------- 3. If still empty, try same folder as EndGUI --------
            if isempty(fails)
                try
                    here = fileparts(mfilename('fullpath'));
                    alt = fullfile(here,'fails.mat');
        
                    if isfile(alt)
                        S = load(alt);
        
                        if isfield(S,'fails')
                            fails = S.fails;
                        else
                            fn = fieldnames(S);
                            if ~isempty(fn)
                                fails = S.(fn{1});
                            end
                        end
                    end
                catch
                end
            end
        
            % -------- 4. UNWRAP nested fails.fails --------
            % Handles fails.fails, fails.fails.fails, etc.
        
            maxDepth = 5;   % safety limit
            depth = 0;
        
            while isstruct(fails) && isscalar(fails) && isfield(fails,'fails') && depth < maxDepth
                fails = fails.fails;
                depth = depth + 1;
            end
        
            % -------- 5. Convert cell → struct array --------
            if iscell(fails)
                try
                    fails = [fails{:}];
                catch
                    fails = [];
                end
            end
        
            % -------- 6. Final validation --------
            if isempty(fails)
                warning("loadFails: No fails data found.");
                return;
            end
        
            if ~isstruct(fails)
                warning("loadFails: Fails is not struct. Type = %s", class(fails));
                fails = [];
                return;
            end
        
        end
        
        function labels = makeRunLabels(app, fails, selectedScenarios)
                      n = numel(fails);
            labels = strings(n,1);

            % Prefer fails(i).scenario if present
            hasScenarioField = isfield(fails,'scenario');

            for i = 1:n
                nm = "";

                if hasScenarioField
                    try
                        nm = string(fails(i).scenario);
                    catch
                        nm = "";
                    end
                end

                if strlength(nm)==0 && ~isempty(selectedScenarios) && i <= numel(selectedScenarios)
                    nm = string(selectedScenarios{i});
                end

                if strlength(nm)==0
                    nm = "Run_" + i;
                end

                % If it's a path, keep just filename
                [~, base, ~] = fileparts(nm);
                if strlength(base) > 0
                    nm = base;
                end

                labels(i) = sprintf("Sim %d: %s", i, nm);
            end
        end


        function k = parseSimIndex(app, label)
             k = [];
            % label: "Sim 3: something"
            t = regexp(char(label), 'Sim\s+(\d+)\s*:', 'tokens', 'once');
            if ~isempty(t)
                k = str2double(t{1});
            end
        end
        
        
        function c = redGreen(app, hasFail)
            if hasFail
                c = [1 0 0];   % red
            else
                c = [0 1 0];   % green
            end
        end
          function c = comfortColor(app, comfortFails)
            % comfortFails: nRuns x 3, 0 pass, 1 fail
            totalFails = sum(comfortFails(:) ~= 0);
            totalElems = numel(comfortFails);

            if totalFails == 0
                c = [0 1 0];     % green
            elseif totalFails == totalElems
                c = [1 0 0];     % red
            else
                c = [1 1 0];     % yellow
            end
        end
        function setAllLampsUnknown(app)
            gray = [0.7 0.7 0.7];
            app.LaneKeepLamp.Color = gray;
            app.LongitudinalAccelerationLamp.Color = gray;
            app.LateralAccelerationLamp.Color = gray;
            app.CurvatureLamp.Color = gray;
            app.CurvatureRateLamp.Color = gray;
            app.JerkLamp.Color = gray;
            app.CollisionDetectionLamp.Color = gray;
            app.ComfortLamp.Color = gray;
        end
       function updatePlotList(app, runIdx)
            % Goal:
            % - If viewing All: show ALL fail graphs
            % - If viewing Sim k: show only that sim's fail graphs
            %
            % Assumptions about plot names:
            % - Files are in app.PlotsFolder
            % - Filenames contain "fail" for fail graphs
            % - Filenames contain scenario/run identifier (e.g. the scenario base name)

            folder = app.PlotsFolder;
            if ~isfolder(folder)
                app.PlotList.Items = {};
                return;
            end

            files = dir(fullfile(folder, '*.png'));
            names = string({files.name});

            % fail filter
            isFail = contains(lower(names), "fail");

            if string(app.ViewDropDown.Value) == "All"
                keep = isFail;
            else
                % try to match by scenario base name (from label)
                label = string(app.ViewDropDown.Value);
                k = app.parseSimIndex(label);
                scenarioKey = "";
                if ~isempty(k)
                    scenarioKey = app.keyForRun(k);
                end
                if strlength(scenarioKey)==0
                    keep = isFail;
                else
                    keep = isFail & contains(names, scenarioKey);
                end
            end

            out = names(keep);

            if isempty(out)
                app.PlotList.Items = {};
            else
                app.PlotList.Items = cellstr(out);
                app.PlotList.Value = app.PlotList.Items{1};
            end
        end

        function key = keyForRun(app, k)
            % Derive a filename key for filtering plots for run k
            key = "";
            if isempty(app.Fails) || k < 1 || k > numel(app.Fails)
                fprintf('keyForRun: Invalid index k=%d (Fails has %d elements)\n', k, numel(app.Fails));
                return;
            end

            if isfield(app.Fails,'scenario')
                try
                    nm = string(app.Fails(k).scenario);
                    [~, base, ~] = fileparts(nm);
                    if strlength(base) > 0
                        key = base;
                    else
                        key = nm;
                    end
                catch
                    key = "";
                end
            end

            if strlength(key)==0 && ~isempty(app.SelectedScenarios) && k <= numel(app.SelectedScenarios)
                nm = string(app.SelectedScenarios{k});
                [~, base, ~] = fileparts(nm);
                if strlength(base) > 0
                    key = base;
                else
                    key = nm;
                end
            end
        end

        function autoShowFeatureImages(app, runIdx)
            % Try to auto-display a relevant fail graph per tab based on feature keywords.
            % This relies on your requirement: "Graphs should be named the same as the lamp feature"
            %
            % Adjust keywords to match your actual filenames.

            folder = app.PlotsFolder;
            if ~isfolder(folder)
                app.clearImages();
                return;
            end

            files = dir(fullfile(folder, '*.png'));
            names = string({files.name});

            % Filter by view (All vs Sim k)
            if string(app.ViewDropDown.Value) == "All"
                viewKeep = true(size(names));
            else
                k = app.parseSimIndex(string(app.ViewDropDown.Value));
                key = "";
                if ~isempty(k), key = app.keyForRun(k); end
                if strlength(key)==0
                    viewKeep = true(size(names));
                else
                    viewKeep = contains(names, key);
                end
            end

            isFail = contains(lower(names),"fail");
            names = names(viewKeep & isFail);

            % Pick first match for each tab
            app.MapImage.ImageSource      = app.pickPlot(folder, names, ["map"]);
            app.SpeedImage.ImageSource    = app.pickPlot(folder, names, ["speed"]);
            app.LanePosImage.ImageSource  = app.pickPlot(folder, names, ["lane", "laneposition", "lanekeep"]);
            app.JerkImage.ImageSource     = app.pickPlot(folder, names, ["jerk"]);
            app.LatAccelImage.ImageSource = app.pickPlot(folder, names, ["lateral", "LatAccel"]);
            app.LongAccelImage.ImageSource= app.pickPlot(folder, names, ["longitudinal", "longlaccel", "LongAccel"]);
            app.CurvatureImage.ImageSource= app.pickPlot(folder, names, ["Curvature"]);
            app.CurvatureRateImage.ImageSource= app.pickPlot(folder, names, ["CurvatureRate"]);
            app.CollisionDetectionImage.ImageSource= app.pickPlot(folder, names, ["Collision"]);
        end

        function fp = pickPlot(app, folder, names, keywords)
            fp = [];
            if isempty(names), return; end
            low = lower(names);

            hit = false(size(low));
            for k = 1:numel(keywords)
                hit = hit | contains(low, lower(string(keywords(k))));
            end

            if any(hit)
                fp = fullfile(folder, char(names(find(hit,1,'first'))));
            end
        end

        function clearImages(app)
            app.MapImage.ImageSource = [];
            app.SpeedImage.ImageSource = [];
            app.LanePosImage.ImageSource = [];
            app.JerkImage.ImageSource = [];
            app.LatAccelImage.ImageSource = [];
            app.LongAccelImage.ImageSource = [];
            app.CurvatureImage.ImageSource = [];
            app.CurvatureRateImage.ImageSource = [];
            app.CollisionDetectionImage.ImageSource = [];
        end
        function viewDropDownChanged(app, ~)
            app.updateView();
            app.populatePlots(); 
        end
        function plotListChanged(app, ~)
            if isempty(app.PlotList.Value), return; end
            app.showPlotOnActiveTab(app.PlotList.Value);
        end

            function populatePlots(app)
                % Plots folder is under the same folder as where you run (or EndGUI.m)
                % Choose ONE of these:
                root = fileparts(mfilename('fullpath'));
               plotsRoot = app.PlotsFolder;

                % here = fileparts(mfilename('fullpath'));
                % app.PlotsFolder = fullfile(here, 'Plots');  % same folder as EndGUI.m
            
                if ~isfolder(app.PlotsFolder)
                    app.PlotList.Items = {};
                    app.ScenarioTextArea.Value = "Plots folder not found: " + string(app.PlotsFolder);
                    app.clearImages();
                    return;
                end
            
                files = dir(fullfile(app.PlotsFolder, '*.png'));
                names = string({files.name});
            
            
                % OPTIONAL: only show fail plots
                % names = names(contains(lower(names),"fail"));
            
                % Filter by view (All vs Sim k)
                names = app.filterPlotsForCurrentView(names);
            
                if isempty(names)
                    app.PlotList.Items = {};
                    app.clearImages();
                    return;
                end
            
                % Put into listbox
                app.PlotList.Items = cellstr(names);
                app.PlotList.Value = app.PlotList.Items{1};
            
                % Show first image automatically
                app.showPlotOnActiveTab(app.PlotList.Value);
            end
            
            
            function namesOut = filterPlotsForCurrentView(app, namesIn)
                % If "All" -> show all (or all fail only if you filtered above)
                if string(app.ViewDropDown.Value) == "All"
                    namesOut = namesIn;
                    return;
                end
            
                % If "Sim k: ..." -> filter by scenario key
                k = app.parseSimIndex(string(app.ViewDropDown.Value));
                key = "";
                if ~isempty(k)
                    key = app.keyForRun(k);
                end
            
                if strlength(key)==0
                    namesOut = namesIn;
                else
                    namesOut = namesIn(contains(namesIn, key));
                end
            end
            
            
            function showPlotOnActiveTab(app, fileName)

                if isempty(fileName)
                    return
                end
            
                fp = fullfile(app.PlotsFolder, fileName);
            
                if ~isfile(fp)
                    return
                end
            
                t = app.TabGroup.SelectedTab;
            
                if t == app.MapTab
                    app.MapImage.ImageSource = fp;
            
                elseif t == app.SpeedTab
                    app.SpeedImage.ImageSource = fp;
            
                elseif t == app.LanePositionTab
                    app.LanePosImage.ImageSource = fp;
            
                elseif t == app.JerkTab
                    app.JerkImage.ImageSource = fp;
            
                elseif t == app.LatAccelTab
                    app.LatAccelImage.ImageSource = fp;
            
                elseif t == app.LonglAccelTab
                    app.LongAccelImage.ImageSource = fp;
                end
            end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disp("Running EndGUI from: " + string(mfilename('fullpath')));


            % View dropdown
            app.ViewDropDownLabel = uilabel(app.UIFigure);
            app.ViewDropDownLabel.Position = [15 420 40 22];
            app.ViewDropDownLabel.Text = 'View';

            app.ViewDropDown = uidropdown(app.UIFigure);
            app.ViewDropDown.Position = [60 420 250 22];
            app.ViewDropDown.Items = {'All'};
            app.ViewDropDown.Value = 'All';
            app.ViewDropDown.ValueChangedFcn = @(src,event) viewDropDownChanged(app,event);

            % Lamps/Labels (left column)
            app.LaneKeepLampLabel = uilabel(app.UIFigure);
            app.LaneKeepLampLabel.HorizontalAlignment = 'right';
            app.LaneKeepLampLabel.Position = [15 325 120 22];
            app.LaneKeepLampLabel.Text = 'Lane Keep';

            app.LaneKeepLamp = uilamp(app.UIFigure);
            app.LaneKeepLamp.Position = [150 328 19 19];

            app.LongitudinalAccelerationLampLabel = uilabel(app.UIFigure);
            app.LongitudinalAccelerationLampLabel.HorizontalAlignment = 'right';
            app.LongitudinalAccelerationLampLabel.Position = [15 290 120 30];
            app.LongitudinalAccelerationLampLabel.Text = {'Longitudinal'; 'Acceleration'};

            app.LongitudinalAccelerationLamp = uilamp(app.UIFigure);
            app.LongitudinalAccelerationLamp.Position = [150 302 19 19];

            app.LateralAccelerationLampLabel = uilabel(app.UIFigure);
            app.LateralAccelerationLampLabel.HorizontalAlignment = 'right';
            app.LateralAccelerationLampLabel.Position = [15 250 120 30];
            app.LateralAccelerationLampLabel.Text = {'Lateral'; 'Acceleration'};

            app.LateralAccelerationLamp = uilamp(app.UIFigure);
            app.LateralAccelerationLamp.Position = [150 262 19 19];

            app.CurvatureLampLabel = uilabel(app.UIFigure);
            app.CurvatureLampLabel.HorizontalAlignment = 'right';
            app.CurvatureLampLabel.Position = [15 220 120 22];
            app.CurvatureLampLabel.Text = 'Curvature';

            app.CurvatureLamp = uilamp(app.UIFigure);
            app.CurvatureLamp.Position = [150 223 19 19];
            app.CurvatureLamp.Color = [0.7 0.7 0.7];

            app.CurvatureRateLampLabel = uilabel(app.UIFigure);
            app.CurvatureRateLampLabel.HorizontalAlignment = 'right';
            %
            app.CurvatureRateLampLabel.Position = [15 190 120 22];
            app.CurvatureRateLampLabel.Text = 'Curvature Rate';

            app.CurvatureRateLamp = uilamp(app.UIFigure);
            %%
            app.CurvatureRateLamp.Position = [150 193 19 19];
            app.CurvatureRateLamp.Color = [0.7 0.7 0.7];

            app.JerkLampLabel = uilabel(app.UIFigure);
            app.JerkLampLabel.HorizontalAlignment = 'right';
            %
            app.JerkLampLabel.Position = [15 155 120 30];
            app.JerkLampLabel.Text = 'Jerk';

            app.JerkLamp = uilamp(app.UIFigure);
            %%
            app.JerkLamp.Position = [150 167 19 19];

            app.CollisionDetectionLabel = uilabel(app.UIFigure);
            app.CollisionDetectionLabel.HorizontalAlignment = 'right';
            %
            app.CollisionDetectionLabel.Position = [15 120 120 30];
            app.CollisionDetectionLabel.Text = {'Collision'; 'Detection'};

            app.CollisionDetectionLamp = uilamp(app.UIFigure);
            %%
            app.CollisionDetectionLamp.Position = [150 90 19 19];

            % Comfort lamp (aggregate of 3)
            app.ComfortLampLabel = uilabel(app.UIFigure);
            app.ComfortLampLabel.HorizontalAlignment = 'right';
            %
            app.ComfortLampLabel.Position = [15 90 120 15];
            app.ComfortLampLabel.Text = 'Comfort';

            app.ComfortLamp = uilamp(app.UIFigure);
            %%
            app.ComfortLamp.Position = [150 128 19 19];


            % TabGroup (right side)
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.Position = [330 80 550 370];

            app.MapTab = uitab(app.TabGroup);          app.MapTab.Title = 'Map';
            app.SpeedTab = uitab(app.TabGroup);        app.SpeedTab.Title = 'Speed';
            app.LanePositionTab = uitab(app.TabGroup); app.LanePositionTab.Title = 'LanePosition';
            app.JerkTab = uitab(app.TabGroup);         app.JerkTab.Title = 'Jerk';
            app.LatAccelTab = uitab(app.TabGroup);     app.LatAccelTab.Title = 'LatAccel';
            app.LonglAccelTab = uitab(app.TabGroup);   app.LonglAccelTab.Title = 'LongAccel';
            app.CurvatureTab = uitab(app.TabGroup);     app.CurvatureTab.Title = 'Curvature';
            app.CurvatureRateTab = uitab(app.TabGroup);   app.CurvatureRateTab.Title = 'CurvatureRate';
            app.CollisionDetectionTab = uitab(app.TabGroup);   app.CollisionDetectionTab.Title = 'CollisionDetection';


            % Images per tab
            app.MapImage = uiimage(app.MapTab);               app.MapImage.Position = [10 10 520 330]; app.MapImage.ScaleMethod = "fit";
            app.SpeedImage = uiimage(app.SpeedTab);           app.SpeedImage.Position = [10 10 520 330]; app.SpeedImage.ScaleMethod = "fit";
            app.LanePosImage = uiimage(app.LanePositionTab);  app.LanePosImage.Position = [10 10 520 330]; app.LanePosImage.ScaleMethod = "fit";
            app.JerkImage = uiimage(app.JerkTab);             app.JerkImage.Position = [10 10 520 330]; app.JerkImage.ScaleMethod = "fit";
            app.LatAccelImage = uiimage(app.LatAccelTab);     app.LatAccelImage.Position = [10 10 520 330]; app.LatAccelImage.ScaleMethod = "fit";
            app.LongAccelImage = uiimage(app.LonglAccelTab);  app.LongAccelImage.Position = [10 10 520 330]; app.LongAccelImage.ScaleMethod = "fit";
            app.CurvatureImage = uiimage(app.CurvatureTab);     app.CurvatureImage.Position = [10 10 520 330]; app.CurvatureImage.ScaleMethod = "fit";
            app.CurvatureRateImage = uiimage(app.CurvatureRateTab);  app.CurvatureRateImage.Position = [10 10 520 330]; app.CurvatureRateImage.ScaleMethod = "fit";
            app.CollisionDetectionImage = uiimage(app.CollisionDetectionTab);  app.CollisionDetectionImage.Position = [10 10 520 330]; app.CollisionDetectionImage.ScaleMethod = "fit";
            
            % Scenario text area (you use it in updateView)
            app.ScenarioTextArea = uitextarea(app.UIFigure);
            app.ScenarioTextArea.Position = [15 360 295 55];
            app.ScenarioTextArea.Editable = 'off';
            app.ScenarioTextArea.Value = "";
  
            
            %app.PlotListLabel = uilabel(app.UIFigure);
            app.PlotList = uilistbox(app.UIFigure);
            app.PlotList.Position = [410 10 470 60];
            app.PlotList.Visible = 'off';
            %app.PlotListLabel.Visible = 'off';
            % (optional) if you later add a callback:
            % app.PlotList.ValueChangedFcn = @(src,event) plotListChanged(app,event);
            app.UIFigure.Visible = 'on';
            app.Fails = app.loadFails();
            % unwrap repeatedly (works for fails.fails, fails.fails.fails, etc.)
            depth = 0;
            while isstruct(app.Fails) && isfield(app.Fails,'fails') && depth < 5
                if isscalar(app.Fails)
                    app.Fails = app.Fails.fails;
                else
                    % if it's an array of wrappers, take first wrapper's inner
                    app.Fails = app.Fails(1).fails;
                end
                depth = depth + 1;
            end
            
            % if cell -> struct array
            if iscell(app.Fails)
                app.Fails = [app.Fails{:}];
            end
            
            disp("Fails fields (first element):");
            disp(fieldnames(app.Fails(1)));
            if iscell(app.Fails)
                app.Fails = [app.Fails{:}];
            end
            
            % If user accidentally loaded a wrapper struct with a field inside
            if isstruct(app.Fails) && isscalar(app.Fails) && isfield(app.Fails,'fails')
                app.Fails = app.Fails.fails;
            end
            
            if ~isstruct(app.Fails)
                error("Fails loaded but is not a struct array. Type: %s", class(app.Fails));
            end

            if isempty(app.Fails)
                app.ViewDropDown.Items = {'All'};
                app.ViewDropDown.Value = 'All';
                app.ScenarioTextArea.Value = "No fails data loaded. Put fails.mat in current folder (or variable 'fails' in base workspace).";
                app.setAllLampsUnknown();
                return;
            end

            % 2) Build run labels (All + each run)
            app.RunLabels = app.makeRunLabels(app.Fails, app.SelectedScenarios);

           app.ViewDropDown.Items = cellstr(["All"; app.RunLabels(:)]);
           app.ViewDropDown.Value = 'All';
            here = fileparts(mfilename('fullpath'));
            app.PlotsFolder = fullfile(here,'Plots');

            app.PlotList.Position = [410 10 470 60];
            app.PlotList.ValueChangedFcn = @(src,event) plotListChanged(app,event);
            app.TabGroup.SelectionChangedFcn = @(src,event) app.showPlotOnActiveTab(app.PlotList.Value);
            
            app.populatePlots();   % fill list + show first plot
            % 3) Update lamps + plots for initial view
            app.updateView();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PassFailLabel = uilabel(app.UIFigure);
            app.PassFailLabel.Text = 'Pass/Fail';
            app.VisualsLabel = uilabel(app.UIFigure);
            app.VisualsLabel.Text = 'Visuals';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EndUserGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end