classdef RR_End < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MapTab                          matlab.ui.container.Tab
        Map                             matlab.ui.control.UIAxes
        SpeedTab                        matlab.ui.container.Tab
        Speed                           matlab.ui.control.UIAxes
        LaneKeepTab                     matlab.ui.container.Tab
        LanePosition                    matlab.ui.control.UIAxes
        LateralAccelerationTab          matlab.ui.container.Tab
        LatAcceleration                 matlab.ui.control.UIAxes
        LongitudeAccelerationTab        matlab.ui.container.Tab
        LongAcceleration                matlab.ui.control.UIAxes
        CurvatureTab                    matlab.ui.container.Tab
        Curvature                       matlab.ui.control.UIAxes
        CurvatureRateTab                matlab.ui.container.Tab
        CurvatureRate                   matlab.ui.control.UIAxes
        JerkTab                         matlab.ui.container.Tab
        Jerk                            matlab.ui.control.UIAxes
        CollisionDetectionTab           matlab.ui.container.Tab
        CollisionDetection              matlab.ui.control.UIAxes
        FailedScenarios                 matlab.ui.control.ListBox
        FailedScenariosLabel            matlab.ui.control.Label
        PassFailResultsLabel            matlab.ui.control.Label
        CollisionDetectionPF            matlab.ui.control.Lamp
        CollisionDetectionLampLabel     matlab.ui.control.Label
        JerkPF                          matlab.ui.control.Lamp
        JerkLampLabel                   matlab.ui.control.Label
        CurvaturePF                     matlab.ui.control.Lamp
        CurvatureLampLabel              matlab.ui.control.Label
        CurvatureRatePF                 matlab.ui.control.Lamp
        CurvatureRateLampLabel          matlab.ui.control.Label
        LongAccelerationPF              matlab.ui.control.Lamp
        LongitudeAccelerationLampLabel  matlab.ui.control.Label
        LatAccelerationPF               matlab.ui.control.Lamp
        LateralAcelerationLampLabel     matlab.ui.control.Label
        ComfortPF                       matlab.ui.control.Lamp
        ComfortLampLabel                matlab.ui.control.Label
        LaneKeepPF                      matlab.ui.control.Lamp
        LaneKeepLampLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        FailsArray % array from fails in matlab worksapce
        projectPath char % Stores project path as text
    end
    
    methods (Access = private)
        function SetLamps(app, idx)
            %colors = {'green', 'red', 'yellow'};
            fails = app.FailsArray;
            
            %Initially set all lamps
            app.LaneKeepPF.Color = 'green';
            app.ComfortPF.Color = 'green';
            app.LatAccelerationPF.Color = 'green';
            app.LongAccelerationPF.Color = 'green'; % Added
            app.CurvaturePF.Color = 'green';
            app.CurvatureRatePF.Color = 'green'; % DV
            app.JerkPF.Color = 'green';
            app.CollisionDetectionPF.Color = 'green';
        
            % Explicit checks
            if fails(idx).LanePosition == 1, app.LaneKeepPF.Color = 'red'; end
            if fails(idx).LongitudinalAcceleration == 1, app.LongAccelerationPF.Color = 'red'; end
            if fails(idx).LateralAcceleration == 1, app.LatAccelerationPF.Color = 'red'; end
            if fails(idx).Curvature == 1, app.CurvaturePF.Color = 'red'; end
            if fails(idx).CurvatureRate == 1, app.CurvaturePF.Color = 'red'; end % DV
            if fails(idx).Jerk == 1, app.JerkPF.Color = 'red'; end
            if fails(idx).CollisionDetection == 1, app.CollisionDetectionPF.Color = 'red'; end
                
            % Comfort (Aggregate Lamp)
            numFailures = fails(idx).LongitudinalAcceleration + ...
                          fails(idx).LateralAcceleration + ...
                          fails(idx).Curvature + ...
                          fails(idx).CurvatureRate + ...
                          fails(idx).Jerk;
                          
            if numFailures > 4
                app.ComfortPF.Color = 'red';
            elseif numFailures >= 1
                app.ComfortPF.Color = 'yellow';
            end

        end
  
        function DisplayPlots(app, selectedIdx)
            scenarioName = app.FailsArray(selectedIdx).scenario;

            SelectedProjectPath = 'SelectedProjectPath.txt';
            rawContentProject = fileread(SelectedProjectPath);
            project = strtrim(rawContentProject);

            %Map plot
            mapPath = fullfile(project,'Plots','Speed and Map',sprintf('%s_%d_Map.png', scenarioName, selectedIdx));
            
            if isfile(mapPath)
                img1 = imread(mapPath);
                image(app.Map, img1);
                app.Map.Visible = 'off'; % Hide axes lines for a clean image look
                title(app.Map, [scenarioName ' - Map']);
            else
                cla(app.Map);
                title(app.Map, 'Map Plot Not Found');
            end

            %Speed plot
            speedPath = fullfile(project,'Plots','Speed and Map',sprintf('%s_%d_Speeds.png', scenarioName, selectedIdx));
            
            if isfile(speedPath)
                img1 = imread(speedPath);
                image(app.Speed, img1);
                app.Speed.Visible = 'off'; % Hide axes lines for a clean image look
                title(app.Speed, [scenarioName ' - speed']);
            else
                cla(app.Speed);
                title(app.Speed, 'Speed Plot Not Found');
            end
            
            
            %lane position plots
            laneStatus = 'Pass';
            
            if app.FailsArray(selectedIdx).LanePosition == 1
                laneStatus = 'Fail';
            end
            
            lanePath = fullfile(project,'Plots',laneStatus,sprintf('%s_%d_LanePosition_%s.png', scenarioName, selectedIdx, laneStatus));
            
            if isfile(lanePath)
                img1 = imread(lanePath);
                image(app.LanePosition, img1);
                app.LanePosition.Visible = 'off'; % Hide axes lines for a clean image look
                title(app.LanePosition, [scenarioName ' - Lane Position']);
            else
                cla(app.LanePosition);
                title(app.LanePosition, 'Lane Position Plot Not Found');
            end
            
            %lat acceleration plots
            lataccelStatus = 'Pass';
            
            if app.FailsArray(selectedIdx).LateralAcceleration == 1
                lataccelStatus = 'Fail';
            end
            
            lataccelPath = fullfile(project,'Plots',lataccelStatus,sprintf('%s_%d_Smoothness_LatAccel_%s.png', scenarioName, selectedIdx, lataccelStatus));
            
            if isfile(lataccelPath)
                img1 = imread(lataccelPath);
                image(app.LatAcceleration, img1);
                app.LatAcceleration.Visible = 'off'; % Hide axes lines for a clean image look
                title(app.LatAcceleration, [scenarioName ' - Acceleration']);
            else
                cla(app.LatAcceleration);
                title(app.LatAcceleration, 'Acceleration Plot Not Found');
            end
            
            %long acceleration plots
            longaccelStatus = 'Pass';
            
            if app.FailsArray(selectedIdx).LongitudinalAcceleration == 1
                longaccelStatus = 'Fail';
            end
            
            longaccelPath = fullfile(project,'Plots',longaccelStatus,sprintf('%s_%d_Smoothness_LongAccel_%s.png', scenarioName, selectedIdx, longaccelStatus));
            
            if isfile(longaccelPath)
                img1 = imread(longaccelPath);
                image(app.LongAcceleration, img1);
                app.LongAcceleration.Visible = 'off'; % Hide axes lines for a clean image look
                title(app.LongAcceleration, [scenarioName ' - Acceleration']);
            else
                cla(app.LongAcceleration);
                title(app.LongAcceleration, 'Acceleration Plot Not Found');
            end
            
            %curvature plots
            curveStatus = 'Pass';
            
            if app.FailsArray(selectedIdx).Curvature == 1
                curveStatus = 'Fail';
            end
            
            curvePath = fullfile(project,'Plots',curveStatus,sprintf('%s_%d_Smoothness_Curvature_%s.png', scenarioName, selectedIdx, curveStatus));
            
            if isfile(curvePath)
                img1 = imread(curvePath);
                image(app.Curvature, img1);
                app.Curvature.Visible = 'off'; % Hide axes lines for a clean image look
                title(app.Curvature, [scenarioName ' - Curvature']);
            else
                cla(app.Curvature);
                title(app.Curvature, 'Curvature Plot Not Found');
            end

            %curvature rate plots
            curveRateStatus = 'Pass';

            if app.FailsArray(selectedIdx).CurvatureRate == 1
                curveRateStatus = 'Fail';
            end
            
            curveRatePath = fullfile(project,'Plots',curveRateStatus,sprintf('%s_%d_Smoothness_CurvatureRate_%s.png', scenarioName, selectedIdx, curveRateStatus));
            
            if isfile(curveRatePath)
                img1 = imread(curveRatePath);
                image(app.CurvatureRate, img1);
                app.CurvatureRate.Visible = 'off'; % Hide axes lines for a clean image look
                title(app.CurvatureRate, [scenarioName ' - Curvature Rate']);
            else
                cla(app.CurvatureRate);
                title(app.CurvatureRate, 'Curvature Rate Plot Not Found');
            end
            
            %jerk plots
            jerkStatus = 'Pass';
            
            if app.FailsArray(selectedIdx).Jerk == 1
                jerkStatus = 'Fail';
            end
            
            jerkPath = fullfile(project,'Plots',jerkStatus,sprintf('%s_%d_Smoothness_Jerk_%s.png', scenarioName, selectedIdx, jerkStatus));
            
            if isfile(jerkPath)
                img1 = imread(jerkPath);
                image(app.Jerk, img1);
                app.Jerk.Visible = 'off'; % Hide axes lines for a clean image look
                title(app.Jerk, [scenarioName ' - Jerk']);
            else
                cla(app.Jerk);
                title(app.Jerk, 'Jerk Plot Not Found');
            end
            
            %Collision plots
            collisionStatus = 'Pass';
            
            if app.FailsArray(selectedIdx).CollisionDetection == 1
                collisionStatus = 'Fail';
            end
            
            collisionPath = fullfile(project,'Plots',collisionStatus,sprintf('%s_%d_CollisionDistance_%s.png', scenarioName, selectedIdx, collisionStatus));
            
            if isfile(collisionPath)
                img1 = imread(collisionPath);
                image(app.CollisionDetection, img1);
                app.CollisionDetection.Visible = 'off'; % Hide axes lines for a clean image look
                title(app.CollisionDetection, [scenarioName ' - Collision Detection']);
            else
                cla(app.CollisionDetection);
                title(app.CollisionDetection, 'Collision Detection Plot Not Found');
            end
            
        end
        
        function ClearAllPlots(app)
            % Clear UIAxes components
            cla(app.Map);
            cla(app.Speed);
            cla(app.LanePosition);
            cla(app.LatAcceleration);
            cla(app.LongAcceleration);
            cla(app.Curvature);
            cla(app.CurvatureRate);
            cla(app.Jerk);
            cla(app.CollisionDetection);

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FailsArray = evalin('base', 'fails');
            
            colors = {'green', 'red', 'yellow'};
            failedRunList = {'All Scenarios'};
            failedIndices = {0};
            
            %Initially set all lamps
            app.LaneKeepPF.Color = 'green';
            app.ComfortPF.Color = 'green';
            app.LatAccelerationPF.Color = 'green';
            app.LongAccelerationPF.Color = 'green';
            app.CurvaturePF.Color = 'green';
            app.CurvatureRatePF.Color = 'green';
            app.JerkPF.Color = 'green';
            app.CollisionDetectionPF.Color = 'green';
            
            for i=1:length(app.FailsArray)
                fails = app.FailsArray;
                
                % Lane Keep
                if fails(i).LanePosition == 1
                    app.LaneKeepPF.Color = 'red';
                end
                
                % Comfort
                numFailures = fails(i).LongitudinalAcceleration + ...
                    fails(i).LateralAcceleration + ...
                    fails(i).Curvature + ...
                    fails(i).CurvatureRate + ...
                    fails(i).Jerk;
                currentRGB = validatecolor(app.ComfortPF.Color);
                redRGB = validatecolor(colors{2});
                
                if numFailures > 4
                    app.ComfortPF.Color = 'red';
                elseif numFailures >= 1 && ~isequal(currentRGB, redRGB)
                    app.ComfortPF.Color = 'yellow';
                end
                
                % Comfort Metrics
                if fails(i).LongitudinalAcceleration == 1
                    app.LongAccelerationPF.Color = 'red';
                end
                if fails(i).LateralAcceleration == 1
                    app.LatAccelerationPF.Color = 'red';
                end
                if fails(i).Curvature == 1
                    app.CurvaturePF.Color = 'red';
                end
                if fails(i).CurvatureRate == 1
                    app.CurvatureRatePF.Color = 'red';
                end
                if fails(i).Jerk == 1
                    app.JerkPF.Color = 'red';
                end
                
                % Collision Detection
                if fails(i).CollisionDetection == 1
                    app.CollisionDetectionPF.Color = 'red';
                end
                
                % Add any fails to the run list
                hasFailed = (fails(i).LanePosition == 1 || ...
                            fails(i).LongitudinalAcceleration == 1 || ... % Updated
                            fails(i).LateralAcceleration == 1 || ...      % Updated
                            fails(i).Curvature == 1 || ...
                            fails(i).CurvatureRate == 1 || ...
                            fails(i).Jerk == 1 || ...
                            fails(i).CollisionDetection == 1);
                                
                if hasFailed
                    % Add the run index to your list
                    failedRunList{end+1} = fails(i).scenario;
                    failedIndices{end+1} = i; % Store the REAL index from fails array of scenario
                end
                
                app.FailedScenarios.Items = failedRunList;
                app.FailedScenarios.ItemsData = [failedIndices{:}];
                drawnow;
            end
        end

        % Value changed function: FailedScenarios
        function FailedScenariosClicked(app, event)
            selectedIdx = app.FailedScenarios.Value;
            
            if selectedIdx == 0
                app.ClearAllPlots();
                app.startupFcn();
            else
                app.SetLamps(selectedIdx)
                %fprintf(app.FailsArray(selectedIdx).scenario)
                app.DisplayPlots(selectedIdx)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LaneKeepLampLabel = uilabel(app.UIFigure);
            app.LaneKeepLampLabel.Text = 'Lane Keep';
            app.LaneKeepPF = uilamp(app.UIFigure);
            app.ComfortLampLabel = uilabel(app.UIFigure);
            app.ComfortLampLabel.Text = 'Comfort';
            app.ComfortPF = uilamp(app.UIFigure);
            app.LateralAcelerationLampLabel = uilabel(app.UIFigure);
            app.LateralAcelerationLampLabel.Text = 'Lateral Aceleration';
            app.LatAccelerationPF = uilamp(app.UIFigure);
            app.LongitudeAccelerationLampLabel = uilabel(app.UIFigure);
            app.LongitudeAccelerationLampLabel.Text = 'Longitude Acceleration';
            app.LongAccelerationPF = uilamp(app.UIFigure);
            app.CurvatureRateLampLabel = uilabel(app.UIFigure);
            app.CurvatureRateLampLabel.Text = 'Curvature Rate';
            app.CurvatureRatePF = uilamp(app.UIFigure);
            app.CurvatureLampLabel = uilabel(app.UIFigure);
            app.CurvatureLampLabel.Text = 'Curvature';
            app.CurvaturePF = uilamp(app.UIFigure);
            app.JerkLampLabel = uilabel(app.UIFigure);
            app.JerkLampLabel.Text = 'Jerk';
            app.JerkPF = uilamp(app.UIFigure);
            app.CollisionDetectionLampLabel = uilabel(app.UIFigure);
            app.CollisionDetectionLampLabel.Text = 'Collision Detection';
            app.CollisionDetectionPF = uilamp(app.UIFigure);
            app.PassFailResultsLabel = uilabel(app.UIFigure);
            app.PassFailResultsLabel.Text = 'Pass/Fail Results';
            app.FailedScenariosLabel = uilabel(app.UIFigure);
            app.FailedScenariosLabel.Text = {'Failed'; 'Scenarios'};
            app.FailedScenarios = uilistbox(app.UIFigure);
            app.FailedScenarios.ValueChangedFcn = createCallbackFcn(app, @FailedScenariosClicked, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MapTab = uitab(app.TabGroup);
            app.MapTab.Title = 'Map';
            app.Map = uiaxes(app.MapTab);
            title(app.Map, 'Title')
            xlabel(app.Map, 'X')
            ylabel(app.Map, 'Y')
            zlabel(app.Map, 'Z')
            app.SpeedTab = uitab(app.TabGroup);
            app.SpeedTab.Title = 'Speed';
            app.Speed = uiaxes(app.SpeedTab);
            title(app.Speed, 'Title')
            xlabel(app.Speed, 'X')
            ylabel(app.Speed, 'Y')
            zlabel(app.Speed, 'Z')
            app.LaneKeepTab = uitab(app.TabGroup);
            app.LaneKeepTab.Title = 'Lane Keep';
            app.LanePosition = uiaxes(app.LaneKeepTab);
            title(app.LanePosition, 'Title')
            xlabel(app.LanePosition, 'X')
            ylabel(app.LanePosition, 'Y')
            zlabel(app.LanePosition, 'Z')
            app.LateralAccelerationTab = uitab(app.TabGroup);
            app.LateralAccelerationTab.Title = 'Lateral Acceleration';
            app.LatAcceleration = uiaxes(app.LateralAccelerationTab);
            title(app.LatAcceleration, 'Title')
            xlabel(app.LatAcceleration, 'X')
            ylabel(app.LatAcceleration, 'Y')
            zlabel(app.LatAcceleration, 'Z')
            app.LongitudeAccelerationTab = uitab(app.TabGroup);
            app.LongitudeAccelerationTab.Title = 'Longitude Acceleration';
            app.LongAcceleration = uiaxes(app.LongitudeAccelerationTab);
            title(app.LongAcceleration, 'Title')
            xlabel(app.LongAcceleration, 'X')
            ylabel(app.LongAcceleration, 'Y')
            zlabel(app.LongAcceleration, 'Z')
            app.CurvatureTab = uitab(app.TabGroup);
            app.CurvatureTab.Title = 'Curvature';
            app.Curvature = uiaxes(app.CurvatureTab);
            title(app.Curvature, 'Title')
            xlabel(app.Curvature, 'X')
            ylabel(app.Curvature, 'Y')
            zlabel(app.Curvature, 'Z')
            app.CurvatureRateTab = uitab(app.TabGroup);
            app.CurvatureRateTab.Title = 'CurvatureRate';
            app.CurvatureRate = uiaxes(app.CurvatureRateTab);
            title(app.CurvatureRate, 'Title')
            xlabel(app.CurvatureRate, 'X')
            ylabel(app.CurvatureRate, 'Y')
            zlabel(app.CurvatureRate, 'Z')
            app.JerkTab = uitab(app.TabGroup);
            app.JerkTab.Title = 'Jerk';
            app.Jerk = uiaxes(app.JerkTab);
            title(app.Jerk, 'Title')
            xlabel(app.Jerk, 'X')
            ylabel(app.Jerk, 'Y')
            zlabel(app.Jerk, 'Z')
            app.CollisionDetectionTab = uitab(app.TabGroup);
            app.CollisionDetectionTab.Title = 'Collision Detection';
            app.CollisionDetection = uiaxes(app.CollisionDetectionTab);
            title(app.CollisionDetection, 'Title')
            xlabel(app.CollisionDetection, 'X')
            ylabel(app.CollisionDetection, 'Y')
            zlabel(app.CollisionDetection, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RR_End

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end