classdef RR_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BlankIntersectionCheckBox      matlab.ui.control.CheckBox
        AdaptiveCruiseControlCheckBox  matlab.ui.control.CheckBox
        EmergencyBrakingCheckBox       matlab.ui.control.CheckBox
        ObstacleDetectionCheckBox      matlab.ui.control.CheckBox
        LowClearanceCheckBox           matlab.ui.control.CheckBox
        AnimalCheckBox                 matlab.ui.control.CheckBox
        RailRoadCrossingCheckBox       matlab.ui.control.CheckBox
        LaneHighwayCheckBox_3          matlab.ui.control.CheckBox
        LaneHighwayCheckBox_2          matlab.ui.control.CheckBox
        LaneHighwayCheckBox            matlab.ui.control.CheckBox
        ProtectedLeftLightCheckBox     matlab.ui.control.CheckBox
        RoundAboutCheckBox             matlab.ui.control.CheckBox
        RandomizeObstaclesCheckBox     matlab.ui.control.CheckBox
        RepeatCountEditField           matlab.ui.control.NumericEditField
        RepeatCountEditFieldLabel      matlab.ui.control.Label
        SignalDectionCheckBox          matlab.ui.control.CheckBox
        LaneDetectionCheckBox          matlab.ui.control.CheckBox
        SignDetectionCheckBox          matlab.ui.control.CheckBox
        FeaturesLabel                  matlab.ui.control.Label
        SelectallScenesCheckBox        matlab.ui.control.CheckBox
        RoadRunnerProjectPathLabel     matlab.ui.control.Label
        ScenesLabel                    matlab.ui.control.Label
        FourWayLightCheckBox           matlab.ui.control.CheckBox
        FourWayStopCheckBox            matlab.ui.control.CheckBox
        RunSimulationButton            matlab.ui.control.Button
        SelectallObstaclesCheckBox     matlab.ui.control.CheckBox
        SelectallBehaviorsCheckBox     matlab.ui.control.CheckBox
        PedestrianCheckBox             matlab.ui.control.CheckBox
        ObstaclesLabel                 matlab.ui.control.Label
        CarCheckBox                    matlab.ui.control.CheckBox
        ClearCheckBox                  matlab.ui.control.CheckBox
        BehaviorsLabel                 matlab.ui.control.Label
        RightCheckBox                  matlab.ui.control.CheckBox
        LeftCheckBox                   matlab.ui.control.CheckBox
        StraightCheckBox               matlab.ui.control.CheckBox
        ChooseProjectPathButton        matlab.ui.control.Button
        RoadRunnerInstallationPathLabel  matlab.ui.control.Label
        ChooseInstallationPathButton   matlab.ui.control.Button
    end

    
    properties (Access = private)
            installPath char        % Stores installation path as text
            projectPath char        % Stores project path as text
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseInstallationPathButton
        function ChooseInstallationPathButtonPushed(app, event)
            folder = uigetdir('', 'Select RoadRunner Installation Folder');
            if isequal(folder, 0)
                disp('Installation folder selection cancelled.');
                return;
            end
        
            disp(['Selected installation folder: ', folder]);
        
            % Save in app property as text
            app.installPath = folder;
        
            % Also write to txt file for RRStartUp
            fid = fopen('SelectedInstallationPath.txt', 'w');
            fprintf(fid, '%s\n', folder);
            fclose(fid);
            disp('Installation path saved to SelectedInstallationPath.txt');
        end

        % Button pushed function: ChooseProjectPathButton
        function ChooseProjectPathButtonPushed(app, event)
            folder = uigetdir('', 'Select RoadRunner Project Folder');
            if isequal(folder, 0)
                disp('Project folder selection cancelled.');
                return;
            end
        
            disp(['Selected project folder: ', folder]);
        
            % Save in app property as text
            app.projectPath = folder;
        
            % Also write to txt file for RRStartUp
            fid = fopen('SelectedProjectPath.txt', 'w');
            fprintf(fid, '%s\n', folder);
            fclose(fid);
            disp('Project path saved to SelectedProjectPath.txt');
        end

        % Value changed function: SelectallBehaviorsCheckBox
        function SelectallBehaviorsCheckBoxValueChanged(app, event)
            val = app.SelectallBehaviorsCheckBox.Value; % true/false
        
            app.StraightCheckBox.Value = val;
            app.LeftCheckBox.Value     = val;
            app.RightCheckBox.Value    = val;
        end

        % Value changed function: SelectallObstaclesCheckBox
        function SelectallObstaclesCheckBoxValueChanged(app, event)
                val = app.SelectallObstaclesCheckBox.Value; % true/false
                
                app.PedestrianCheckBox.Value = val;
                app.CarCheckBox.Value = val;
                app.ClearCheckBox.Value = val;
        end

        % Value changed function: SelectallScenesCheckBox
        function SelectallScenesCheckBoxValueChanged(app, event)
            val = app.SelectallScenesCheckBox.Value;
            
            app.FourWayStopCheckBox.Value = val;
            app.FourWayLightCheckBox.Value = val;
        end

        % Value changed function: SignDetectionCheckBox
        function SignDetectionCheckBoxValueChanged(app, event)
            val = app.SignDetectionCheckBox.Value;
            
            app.FourWayStopCheckBox.Value = val;
            app.ClearCheckBox.Value = val;
            app.StraightCheckBox.Value = val;
            app.RightCheckBox.Value = val;
            app.LeftCheckBox.Value = val;
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
                    % Basic safety checks: make sure paths were chosen
                    if isempty(app.installPath) || isempty(app.projectPath)
                        uialert(app.UIFigure, 'Please choose both installation and project paths.', 'Missing Paths');
                        return;
                    end
                
                    % 1) Build ObstacleTypes and Obstacle Locations cell array
                    ObstacleTypes = {};
                    ObstacleLocations = {};
                    if app.ClearCheckBox.Value
                        ObstacleTypes{end+1} = 'Clear';
                        ObstacleLocations{end+1} = 'NoObstacle';
                    end
                    if app.PedestrianCheckBox.Value
                        % Add both variations for Pedestrian
                        ObstacleTypes{end+1} = 'Pedestrian';
                        ObstacleLocations{end+1} = 'Close';
                        ObstacleTypes{end+1} = 'Pedestrian';
                        ObstacleLocations{end+1} = 'Far';
                    end
                    if app.CarCheckBox.Value
                        % Add both variations for Car
                        ObstacleTypes{end+1} = 'Car';
                        ObstacleLocations{end+1} = 'Parrallel';
                        ObstacleTypes{end+1} = 'Car';
                        ObstacleLocations{end+1} = 'Perpendicular';
                    end
                
                    % 2) Build BehaviorTypes cell array
                    BehaviorTypes = {};
                    if app.StraightCheckBox.Value
                        BehaviorTypes{end+1} = 'Straight';
                    end
                    if app.LeftCheckBox.Value
                        BehaviorTypes{end+1} = 'Left';
                    end
                    if app.RightCheckBox.Value
                        BehaviorTypes{end+1} = 'Right';
                    end
                
                    % 3) Define SceneTypes
                    SceneTypes = {};
                    if app.FourWayLightCheckBox.Value
                        SceneTypes{end+1} = 'Four Way Light';
                    end
                    if app.FourWayStopCheckBox.Value
                        SceneTypes{end+1} = 'Four Way Stop';
                    end

              
                    if isempty(ObstacleTypes) || isempty(BehaviorTypes)
                        uialert(app.UIFigure, 'Please select at least one obstacle and one behavior.', 'Selections Required');
                        return;
                    end

                    % 4) Add any additionaly conditions depending on scene selection
                    Conditions = {};
                    if app.FourWayLightCheckBox.Value
                        Conditions{end+1} = 'Green';
                        Conditions{end+1} = 'Red';
                        Conditions{end+1} = 'YellowGo';
                        Conditions{end+1} = 'YellowStop';
                    end
                    
                    % 5) Handle Repeate Runs and Randmozie Obstacles
                    RepeatCount = app.RepeatCountEditField.Value;
                    Randomize = app.RandomizeObstaclesCheckBox.Value;
                    
                    % 6) Save parameter.mat in the PROJECT folder
                    paramFile = fullfile(app.projectPath, 'parameter.mat');
                    save(paramFile, 'SceneTypes', 'ObstacleTypes', 'ObstacleLocations', 'BehaviorTypes', 'RepeatCount', 'Randomize', 'Conditions');
                    disp(['parameter.mat saved in: ', paramFile]);
                
                    % 7) Ensure the txt files are up to date (RRStartUp reads these)
                    fid = fopen('SelectedInstallationPath.txt','w');
                    fprintf(fid, '%s\n', app.installPath);
                    fclose(fid);
                
                    fid = fopen('SelectedProjectPath.txt','w');
                    fprintf(fid, '%s\n', app.projectPath);
                    fclose(fid);
                
                    % 8) Call RRStartUp.m directly (no try/catch using app)
                    cd(app.projectPath);
                    evalin('base', 'StartUp');

        end

        % Value changed function: LaneDetectionCheckBox
        function LaneDetectionCheckBoxValueChanged(app, event)
            val = app.LaneDetectionCheckBox.Value;
            
            app.FourWayStopCheckBox.Value = val;
            app.ClearCheckBox.Value = val;
            app.SelectallBehaviorsCheckBox.Value = val;
            SelectallBehaviorsCheckBoxValueChanged(app, [])
        end

        % Value changed function: SignalDectionCheckBox
        function SignalDectionCheckBoxValueChanged(app, event)
            val = app.SignalDectionCheckBox.Value;
            
            app.FourWayLightCheckBox.Value = val;
            app.ClearCheckBox.Value = val;
            app.StraightCheckBox.Value = val;
            app.RightCheckBox.Value = val;
            app.LeftCheckBox.Value = val;
        end

        % Value changed function: ObstacleDetectionCheckBox
        function ObstacleDetectionCheckBoxValueChanged(app, event)
            val = app.ObstacleDetectionCheckBox.Value;
            
            app.BlankIntersectionCheckBox.Value = val;
            app.StraightCheckBox.Value = val;
            app.RightCheckBox.Value = val;
            app.LeftCheckBox.Value = val;
            app.SelectallObstaclesCheckBox.Value = val;
            SelectallObstaclesCheckBoxValueChanged(app, [])
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChooseInstallationPathButton = uibutton(app.UIFigure, 'push');
            app.ChooseInstallationPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseInstallationPathButtonPushed, true);
            app.ChooseInstallationPathButton.Text = 'Choose Installation Path';
            app.RoadRunnerInstallationPathLabel = uilabel(app.UIFigure);
            app.RoadRunnerInstallationPathLabel.Text = 'RoadRunner Installation Path';
            app.ChooseProjectPathButton = uibutton(app.UIFigure, 'push');
            app.ChooseProjectPathButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseProjectPathButtonPushed, true);
            app.ChooseProjectPathButton.Text = 'Choose Project Path';
            app.StraightCheckBox = uicheckbox(app.UIFigure);
            app.StraightCheckBox.Text = 'Straight';
            app.LeftCheckBox = uicheckbox(app.UIFigure);
            app.LeftCheckBox.Text = 'Left';
            app.RightCheckBox = uicheckbox(app.UIFigure);
            app.RightCheckBox.Text = 'Right';
            app.BehaviorsLabel = uilabel(app.UIFigure);
            app.BehaviorsLabel.Text = 'Behaviors ';
            app.ClearCheckBox = uicheckbox(app.UIFigure);
            app.ClearCheckBox.Text = 'Clear';
            app.CarCheckBox = uicheckbox(app.UIFigure);
            app.CarCheckBox.Text = 'Car';
            app.ObstaclesLabel = uilabel(app.UIFigure);
            app.ObstaclesLabel.Text = 'Obstacles';
            app.PedestrianCheckBox = uicheckbox(app.UIFigure);
            app.PedestrianCheckBox.Text = 'Pedestrian';
            app.SelectallBehaviorsCheckBox = uicheckbox(app.UIFigure);
            app.SelectallBehaviorsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectallBehaviorsCheckBoxValueChanged, true);
            app.SelectallBehaviorsCheckBox.Text = 'Select all Behaviors';
            app.SelectallObstaclesCheckBox = uicheckbox(app.UIFigure);
            app.SelectallObstaclesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectallObstaclesCheckBoxValueChanged, true);
            app.SelectallObstaclesCheckBox.Text = 'Select all Obstacles';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation ';
            app.FourWayStopCheckBox = uicheckbox(app.UIFigure);
            app.FourWayStopCheckBox.Text = 'Four Way Stop';
            app.FourWayLightCheckBox = uicheckbox(app.UIFigure);
            app.FourWayLightCheckBox.Text = 'Four Way Light';
            app.ScenesLabel = uilabel(app.UIFigure);
            app.ScenesLabel.Text = 'Scenes';
            app.RoadRunnerProjectPathLabel = uilabel(app.UIFigure);
            app.RoadRunnerProjectPathLabel.Text = 'RoadRunner Project Path';
            app.SelectallScenesCheckBox = uicheckbox(app.UIFigure);
            app.SelectallScenesCheckBox.ValueChangedFcn = createCallbackFcn(app, @SelectallScenesCheckBoxValueChanged, true);
            app.SelectallScenesCheckBox.Text = 'Select all Scenes';
            app.FeaturesLabel = uilabel(app.UIFigure);
            app.FeaturesLabel.Text = 'Features';
            app.SignDetectionCheckBox = uicheckbox(app.UIFigure);
            app.SignDetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SignDetectionCheckBoxValueChanged, true);
            app.SignDetectionCheckBox.Text = 'Sign Detection';
            app.LaneDetectionCheckBox = uicheckbox(app.UIFigure);
            app.LaneDetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @LaneDetectionCheckBoxValueChanged, true);
            app.LaneDetectionCheckBox.Text = 'Lane Detection';
            app.SignalDectionCheckBox = uicheckbox(app.UIFigure);
            app.SignalDectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @SignalDectionCheckBoxValueChanged, true);
            app.SignalDectionCheckBox.Text = 'Signal Dection';
            app.RepeatCountEditFieldLabel = uilabel(app.UIFigure);
            app.RepeatCountEditFieldLabel.Text = 'Repeat Count';
            app.RepeatCountEditField = uieditfield(app.UIFigure, 'numeric');
            app.RandomizeObstaclesCheckBox = uicheckbox(app.UIFigure);
            app.RandomizeObstaclesCheckBox.Text = 'Randomize Obstacles';
            app.RoundAboutCheckBox = uicheckbox(app.UIFigure);
            app.RoundAboutCheckBox.Text = 'Round About';
            app.ProtectedLeftLightCheckBox = uicheckbox(app.UIFigure);
            app.ProtectedLeftLightCheckBox.Text = 'Protected Left Light';
            app.LaneHighwayCheckBox = uicheckbox(app.UIFigure);
            app.LaneHighwayCheckBox.Text = '1 Lane Highway';
            app.LaneHighwayCheckBox_2 = uicheckbox(app.UIFigure);
            app.LaneHighwayCheckBox_2.Text = '2 Lane Highway';
            app.LaneHighwayCheckBox_3 = uicheckbox(app.UIFigure);
            app.LaneHighwayCheckBox_3.Text = '3 Lane Highway';
            app.RailRoadCrossingCheckBox = uicheckbox(app.UIFigure);
            app.RailRoadCrossingCheckBox.Text = 'Rail Road Crossing';
            app.AnimalCheckBox = uicheckbox(app.UIFigure);
            app.AnimalCheckBox.Text = 'Animal';
            app.LowClearanceCheckBox = uicheckbox(app.UIFigure);
            app.LowClearanceCheckBox.Text = 'Low Clearance';
            app.ObstacleDetectionCheckBox = uicheckbox(app.UIFigure);
            app.ObstacleDetectionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ObstacleDetectionCheckBoxValueChanged, true);
            app.ObstacleDetectionCheckBox.Text = 'Obstacle Detection';
            app.EmergencyBrakingCheckBox = uicheckbox(app.UIFigure);
            app.EmergencyBrakingCheckBox.Text = 'Emergency Braking';
            app.AdaptiveCruiseControlCheckBox = uicheckbox(app.UIFigure);
            app.AdaptiveCruiseControlCheckBox.Text = 'Adaptive Cruise Control';
            app.BlankIntersectionCheckBox = uicheckbox(app.UIFigure);
            app.BlankIntersectionCheckBox.Text = 'Blank Intersection';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RR_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end