classdef ResultsSelection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DisplayResultsButton          matlab.ui.control.Button
        ChooseSimulationResultsLabel  matlab.ui.control.Label
    end

    properties (Access = public)
        RunAllCheckBox                matlab.ui.control.CheckBox
        CheckBox                      matlab.ui.control.CheckBox
    end
    
    properties (Access = private)
        rrScenarios cell = {}
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.RunAllCheckBox = uicheckbox(app.UIFigure);
            app.RunAllCheckBox.Text = 'Run all scenarios';
            app.RunAllCheckBox.Position = [20 445 150 22];
            app.RunAllCheckBox.ValueChangedFcn = @(src,event) SelectAll(app,event);

            idx = 1;
            p = load('parameter.mat');
            rrProjectPath = strtrim(fileread('SelectedProjectPath.txt'));
            for i = 1:numel(p.SceneTypes)
                sceneName = p.SceneTypes{i};
                sceneNameNoSpaces = sceneName(sceneName ~= ' '); % Clean whitespace
                addpath(fullfile(rrProjectPath, 'Projects', sceneName));
                
                % Notice: We loop through ObstacleTypes and use the same index for Locations
                for j = 1:numel(p.ObstacleTypes)
                    for w = 1:numel(p.BehaviorTypes)
                        % Build filename using index 'j' for both Type and Location
                        scenario = fullfile(rrProjectPath, sprintf('/Projects/%s/Scenarios/%s_%s_%s_%s.rrscenario', ...
                            sceneName, sceneNameNoSpaces, p.ObstacleTypes{j}, p.ObstacleLocations{j}, p.BehaviorTypes{w}));
                        app.rrScenarios{idx} = scenario;
                        app.CheckBox(idx) = uicheckbox(app.UIFigure);
                        app.CheckBox(idx).Text = sprintf('%s_%s_%s_%s',sceneNameNoSpaces, p.ObstacleTypes{j}, p.ObstacleLocations{j}, p.BehaviorTypes{w});
                        app.CheckBox(idx).Position = [20 440-(idx)*20 400 30];
                        idx = idx + 1;
                    end
                end
            end
            app.UIFigure.Visible = 'on';

        end

        % Button down function: UIFigure
        function SelectAll(app, event)
            
            val = app.RunAllCheckBox.Value; % true/false
        
            for i = 1:numel(app.CheckBox)
                if isvalid(app.CheckBox(i))
                    app.CheckBox(i).Value = val;
                end
            end

        end

        % Button pushed function: DisplayResultsButton
        function DisplayResultsButtonPushed(app, event)
            selected = {};
            for i = 1:numel(app.CheckBox)
                if app.CheckBox(i).Value
                    selected{end+1} = app.CheckBox(i).Text; 
                end
            end

            if isempty(selected)
                uialert(app.UIFigure,'Select at least one scenario.','No selection');
                return;
            end

            assignin('base','SelectedSimulations',selected);
            EndUserGUI
            delete(app);   % close window
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @SelectAll, true);
            app.ChooseSimulationResultsLabel = uilabel(app.UIFigure);
            app.ChooseSimulationResultsLabel.Text = 'Choose Simulation Results';
            app.DisplayResultsButton = uibutton(app.UIFigure, 'push');
            app.DisplayResultsButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayResultsButtonPushed, true);
            app.DisplayResultsButton.Text = 'Display Results';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResultsSelection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end